classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GraphoptimizerUIFigure      matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        SettingsPanel               matlab.ui.container.Panel
        ObjectiveFunctionEditFieldLabel  matlab.ui.control.Label
        ObjectiveFunctionEditField  matlab.ui.control.EditField
        startyEditFieldLabel        matlab.ui.control.Label
        startyEditField             matlab.ui.control.NumericEditField
        startxEditFieldLabel        matlab.ui.control.Label
        startxEditField             matlab.ui.control.NumericEditField
        yrangeEditFieldLabel        matlab.ui.control.Label
        yrangeEditField             matlab.ui.control.EditField
        xrangeEditFieldLabel        matlab.ui.control.Label
        xrangeEditField             matlab.ui.control.EditField
        toleranceEditFieldLabel     matlab.ui.control.Label
        toleranceEditField          matlab.ui.control.NumericEditField
        MaxIterEditFieldLabel       matlab.ui.control.Label
        MaxIterEditField            matlab.ui.control.NumericEditField
        ShowGradientFieldCheckBox   matlab.ui.control.CheckBox
        AlgorithmDropDown           matlab.ui.control.DropDown
        AlphaEditFieldLabel         matlab.ui.control.Label
        AlphaEditField              matlab.ui.control.NumericEditField
        PLOTButton                  matlab.ui.control.Button
        amountpointsEditFieldLabel  matlab.ui.control.Label
        amountpointsEditField       matlab.ui.control.NumericEditField
        RightPanel                  matlab.ui.container.Panel
        GraphicsPanel               matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function load_states(app)
            filename = 'state.mat';
        
            if ~exist( filename ) 
                return
            end
            
            load( filename );
            app.ObjectiveFunctionEditField.Value = state.objective_function;
            app.xrangeEditField.Value = state.xrange;
            app.yrangeEditField.Value = state.yrange;
            app.amountpointsEditField.Value = state.amount_points;
            app.startxEditField.Value = state.x0;
            app.startyEditField.Value = state.y0;
            app.toleranceEditField.Value = state.tol;
            app.MaxIterEditField.Value = state.maxiter;
            app.AlphaEditField.Value = state.alpha;
        end
        
        %% function which save state of the UI
        function save_states(app)
            state.objective_function = app.ObjectiveFunctionEditField.Value;
            state.xrange          = app.xrangeEditField.Value;
            state.yrange          = app.yrangeEditField.Value;
            state.amount_points   = app.amountpointsEditField.Value;
            state.x0              = app.startxEditField.Value;
            state.y0              = app.startyEditField.Value;
            state.tol             = app.toleranceEditField.Value;
            state.alpha           = app.AlphaEditField.Value;
            state.maxiter         = app.MaxIterEditField.Value;
            
            save( 'state.mat', 'state' );    
        end
        
        %% parse range line
        function [ Start, End ] = parse_range( app, range_str )
            ranges = strsplit( range_str, ',' );
         
            Start = str2double( char( ranges( 1, 1 ) ) );
            End   = str2double( char( ranges( 1, 2 ) ) );
        end
        
        %% convert from string to range
        function rng = to_range( app, range_str )
            if range_str == ""
                rng = 0:0:0;
                return
            end
            
            [ Start, End ] = parse_range( app, range_str );
            
            rng = linspace( Start, End, app.amountpointsEditField.Value );
        end
    
        function obj_func = to_function( app, function_as_string )
            function_as_string = strrep( function_as_string, '^', '.^' );
            function_as_string = strrep( function_as_string, '*', '.*' );
            
            obj_func = str2func( '@(x, y) ' + function_as_string );    
        end
        
        %% convert from string to range
        function is_valid = is_point_valid( app, range_str, value )
            [ Start, End ] = parse_range( app, range_str );
            is_valid = ( value > Start && value < End );
        end
        
        function result = validate_range(app, some_range)
            range_str = convertCharsToStrings(some_range.Value);
            
            if range_str == ""
                result = false;
                return;
            end
            
            if isempty( regexp(range_str,'-?\d+,-?\d+','match') )
                uialert(app.GraphoptimizerUIFigure,'Input Format is Wrong! Expected: num1,num2','Input Error');
                some_range.Value = '';
                result = false;
                return;
            end
            
            [ Start, End ] = parse_range( app, range_str );
            
            if Start == End
                uialert(app.GraphoptimizerUIFigure,'Wrong Range start==end!','Input Error');
                some_range.Value = '';
                result = false;
                return;
            end
            result = true;
        end
    
    
        function plot_graph( app, X, Y, start_point, objective_func )
            
            %hold( app.UIAxes, 'off' );
            
            %hold( app.UIAxes, 'on' );
            
            Z = objective_func( X, Y );
             
            %% Plot gradient field
            if app.ShowGradientFieldCheckBox.Value == 1
            
                [U,V,W] = surfnorm(app.UIAxes, X,Y,Z);
                
                U = (-1)*U;
                V = (-1)*V;
                W = (-1)*W;
                
                quiver3( app.UIAxes, X, Y, Z, U, V, W, 0.0001 );
                hold( app.UIAxes, 'on' );
            end
            
            %% Plot Graph
            surfc( app.UIAxes, X, Y, Z );
            hold( app.UIAxes, 'on' );
            
            xlabel( app.UIAxes, 'X' );
            ylabel( app.UIAxes, 'Y' );
        
            z_value = objective_func( start_point(1), start_point(2) );
                       
            plot3( app.UIAxes, start_point(1), start_point(2), z_value,'g*', 'LineWidth', 15 );
               
            grid( app.UIAxes, 'on' );
            
            z_height = max( app.UIAxes.ZLim );            
            str = [ '    StartPoint=' num2str( z_value ) ];
            text( app.UIAxes, start_point(1), start_point(2), z_height, str, 'fontweight', 'bold' );

            plot3( app.UIAxes, [start_point(1) start_point(1)], [start_point(2) start_point(2)], [z_value z_height], ...
                '--', 'Color', 'green', 'LineWidth', 2 );
            
            %hold( app.UIAxes, 'on' );
           
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load_states(app);
        end

        % Close request function: GraphoptimizerUIFigure
        function GraphoptimizerUIFigureCloseRequest(app, event)
            save_states(app)
            delete(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.GraphoptimizerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {600, 600};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {311, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            cla( app.UIAxes, "reset" );
            
            save_states( app );

            objective_function = app.ObjectiveFunctionEditField.Value;
            xrange             = app.xrangeEditField.Value;
            yrange             = app.yrangeEditField.Value;
            amount_points      = app.amountpointsEditField.Value;
            x0                 = app.startxEditField.Value;
            y0                 = app.startyEditField.Value;
            tol                = app.toleranceEditField.Value;
            maxiter            = app.MaxIterEditField.Value;
            alpha              = app.AlphaEditField.Value;
            
            if isempty(objective_function)
                app.ObjectiveFunctionEditField.BackgroundColor = 'red';
                uialert(app.GraphoptimizerUIFigure,'Object Function was not set!','Input Error');
                return
            end

            if isempty(xrange) && isempty(yrange)
                app.xrangeEditField.BackgroundColor = 'red';
                app.yrangeEditField.BackgroundColor = 'red';
                uialert(app.GraphoptimizerUIFigure,'[X,Y]-Ranges were not set!','Input Error');
                return
            end
                        
            %% FUNCTION convertion
            objective_func_original = to_function( app, string( objective_function ) );
            objective_func  = @(point) objective_func_original( point(:,1), point(:,2) );
            
            %% Get Algorighm type
            optimization_type = convertCharsToStrings( app.AlgorithmDropDown.Value );
            
            %% Initial position
            start_point = [ x0, y0 ];
            
            %% RANGE validation
            if isempty( yrange ) 
                yrange = xrange;
            elseif isempty( xrange )
                xrange = yrange;
            end
                       
            is_point_within_range = @( point ) is_point_valid( app, xrange, point(1) ) && is_point_valid( app, yrange, point(2) );

            activate_logs = false;
            
            traces = [];
            
            %% Start optimization
            if optimization_type == 'Gradient Descent'
                
                [ error_msg, traces ] = gradient_descent( objective_func_original, ...
                    start_point, maxiter, tol, alpha, ...
                    is_point_within_range, activate_logs );
                
                if ~isempty(error_msg)
                    uialert(app.GraphoptimizerUIFigure, error_msg, 'Computation Error');
                    %% Replot graph
                    plot_graph( app, X, Y, start_point, objective_func_original );
                end
                
            elseif optimization_type == 'Newton Multidimensional'
   
                [error_msg, traces] = newton_multidimensional( objective_func_original, ...
                    start_point, maxiter, tol, is_point_within_range, activate_logs );
                
                if ~isempty(error_msg)
                    uialert(app.GraphoptimizerUIFigure, error_msg, 'Computation Error');
                    %% Replot graph
                    plot_graph( app, X, Y, start_point, objective_func_original );
                end
                
            elseif optimization_type == 'Nelder Mead'
 
                [ traces, ~ ] = nelder_mead( objective_func, start_point, maxiter, is_point_within_range );
                
            elseif optimization_type == 'Simulated Annealing'
                
                traces = simulated_annealing( objective_func, start_point, maxiter, tol, is_point_within_range, activate_logs );                               
            end
            
            if ~isempty( traces )
                
                %axis( app.UIAxes, 'tight' );
                % Reset graphic from axis
                %cla( app.UIAxes, "reset" );
                %cla( app.UIAxes );
                %hold( app.UIAxes, 'off' ); 
                sz = size( traces );
                last_point = traces(sz(1), :);

%                 x_official_min = min( to_range( app, xrange ) );
%                 x_official_max = max( to_range( app, xrange ) );
%                 y_official_min = min( to_range( app, yrange ) );
%                 y_official_max = max( to_range( app, yrange ) );
%                
%                 x_start = min( [ min( [ traces(:,1) ] ), x_official_min ] );
%                 x_end = max( [ max( [ traces(:,1) ] ), x_official_max ] ) ;
%                 y_start = min( [ min( [ traces(:,2) ] ), y_official_min ] );
%                 y_end = max( [ max( [ traces(:,2) ] ), y_official_max ] );
%                 
%                 x_rng = linspace( x_start, x_end, app.amountpointsEditField.Value );
%                 y_rng = linspace( y_start, y_end, app.amountpointsEditField.Value );
                
%                 [XX,YY] = meshgrid( x_rng, y_rng );                                    
%                 plot_graph( app, XX, YY, start_point, objective_func_original );
%                 hold( app.UIAxes, 'on' );
%                 
%                 plot3( app.UIAxes, traces(:, 1), traces(:,2), traces(:, 3), '-o', 'Color', 'black', 'LineWidth', 2 );
%                 plot3( app.UIAxes, traces(:, 1), traces(:,2), traces(:, 3), '.', 'Color', 'red', 'MarkerSize', 20 );
%                 hold( app.UIAxes, 'on' );
%                 
% %                 for i=1:3:sz
% %                     pts1 = traces(i,:);
% %                     pts2 = traces(i+1,:);                    
% %                     pts3 = traces(i+2,:);
% %                     
% %                     plot3( app.UIAxes, [pts1(1) pts2(1)], [pts1(2) pts2(2)], [pts1(3) pts2(3)], '-o', 'Color', 'black', 'LineWidth', 2 );                                    
% %                     
% %                     hold( app.UIAxes, 'on' ); 
% %                     
% %                     plot3( app.UIAxes, [pts1(1) pts3(1)], [pts1(2) pts3(2)], [pts1(3) pts3(3)], '-o', 'Color', 'black', 'LineWidth', 2 );                                    
% %                     plot3( app.UIAxes, [pts2(1) pts3(1)], [pts2(2) pts3(2)], [pts2(3) pts3(3)], '-o', 'Color', 'black', 'LineWidth', 2 );                                    
% %                     
% %                     %plot3( app.UIAxes, pts2(1), pts2(2), pts2(3), '-o', 'Color', 'black', 'LineWidth', 2 );                                    
% %                     %plot3( app.UIAxes, pts3(1), pts3(2), pts3(3), '-o', 'Color', 'black', 'LineWidth', 2 );                
% %                                         
% %                 end
% %                 
                plot3( app.UIAxes, traces(:, 1), traces(:,2), traces(:, 3), '-o', 'Color', 'black', 'LineWidth', 2 );                
                hold( app.UIAxes, 'on' ); 
                
                plot3( app.UIAxes, traces(:, 1), traces(:,2), traces(:, 3), '.', 'Color', 'red', 'MarkerSize', 20 );
                
                plot3( app.UIAxes, last_point(1), last_point(2), last_point(3), '.', 'Color', 'cyan', 'MarkerSize', 40 );
                
                x_diff = 0;%( last_point(1) - start_point(1) ) / 7;
                y_diff = 0;% ( last_point(2) - start_point(2) ) / 7;
                
                x_rng = linspace( min( app.UIAxes.XLim ) - x_diff, max( app.UIAxes.XLim ) - x_diff, app.amountpointsEditField.Value );
                y_rng = linspace( min( app.UIAxes.YLim ) - y_diff, max( app.UIAxes.YLim ) - y_diff, app.amountpointsEditField.Value );
                
                [XX,YY] = meshgrid( x_rng, y_rng );                                    
                plot_graph( app, XX, YY, start_point, objective_func_original );
                                               
                z_height = max( app.UIAxes.ZLim );
                
                text( app.UIAxes, last_point(1), last_point(2), z_height, ['    Minimum=' num2str( last_point(3) )], 'fontweight', 'bold' );

                plot3( app.UIAxes, [last_point(1) last_point(1)], [last_point(2) last_point(2)], [z_height last_point(3)], '--', 'Color', 'cyan', 'LineWidth', 2 );
                
                
                axis( app.UIAxes, 'fill' );
            else            
                [X,Y] = meshgrid( to_range( app, xrange ), to_range( app, yrange ) );
                                    
                plot_graph( app, X, Y, start_point, objective_func_original );
            end
        end

        % Value changed function: ObjectiveFunctionEditField
        function ObjectiveFunctionEditFieldValueChanged(app, event)
            app.ObjectiveFunctionEditField.BackgroundColor = 'white';
        end

        % Value changed function: xrangeEditField
        function xrangeEditFieldValueChanged(app, event)

            if validate_range( app, app.xrangeEditField ) == true
                app.xrangeEditField.BackgroundColor = 'white';
                app.yrangeEditField.BackgroundColor = 'white';
            end
        end

        % Value changed function: yrangeEditField
        function yrangeEditFieldValueChanged(app, event)

            if validate_range( app, app.yrangeEditField ) == true
                app.xrangeEditField.BackgroundColor = 'white';
                app.yrangeEditField.BackgroundColor = 'white';
            end
        end

        % Value changed function: AlgorithmDropDown
        function AlgorithmDropDownValueChanged(app, event)

            app.AlphaEditField.Enable = true;
            app.toleranceEditField.Enable = true;
            
            optimization_type = convertCharsToStrings( app.AlgorithmDropDown.Value );
            
            if optimization_type == 'Newton Multidimensional'
                app.AlphaEditField.Enable = false;
            elseif optimization_type == 'Simulated Annealing'
                app.AlphaEditField.Enable = false;
            elseif optimization_type == 'Nelder Mead'
                app.AlphaEditField.Enable = false;
                app.toleranceEditField.Enable = false;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GraphoptimizerUIFigure = uifigure('Visible', 'off');
            app.GraphoptimizerUIFigure.Name = 'Graph optimizer';
            app.GraphoptimizerUIFigure.CloseRequestFcn = createCallbackFcn(app, @GraphoptimizerUIFigureCloseRequest, true);
            app.GraphoptimizerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.GraphoptimizerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SettingsPanel = uipanel(app.LeftPanel);
            app.SettingsPanel.Title = 'Settings';
            app.ObjectiveFunctionEditFieldLabel = uilabel(app.SettingsPanel);
            app.ObjectiveFunctionEditFieldLabel.Text = 'Objective Function';
            app.ObjectiveFunctionEditField = uieditfield(app.SettingsPanel, 'text');
            app.ObjectiveFunctionEditField.ValueChangedFcn = createCallbackFcn(app, @ObjectiveFunctionEditFieldValueChanged, true);
            app.startyEditFieldLabel = uilabel(app.SettingsPanel);
            app.startyEditFieldLabel.Text = 'start-y';
            app.startyEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.startxEditFieldLabel = uilabel(app.SettingsPanel);
            app.startxEditFieldLabel.Text = 'start-x';
            app.startxEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.yrangeEditFieldLabel = uilabel(app.SettingsPanel);
            app.yrangeEditFieldLabel.Text = 'y-range';
            app.yrangeEditField = uieditfield(app.SettingsPanel, 'text');
            app.yrangeEditField.ValueChangedFcn = createCallbackFcn(app, @yrangeEditFieldValueChanged, true);
            app.xrangeEditFieldLabel = uilabel(app.SettingsPanel);
            app.xrangeEditFieldLabel.Text = 'x-range';
            app.xrangeEditField = uieditfield(app.SettingsPanel, 'text');
            app.xrangeEditField.ValueChangedFcn = createCallbackFcn(app, @xrangeEditFieldValueChanged, true);
            app.xrangeEditField.Value = '-5,5';
            app.toleranceEditFieldLabel = uilabel(app.SettingsPanel);
            app.toleranceEditFieldLabel.Text = 'tolerance';
            app.toleranceEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.toleranceEditField.Limits = [0 Inf];
            app.toleranceEditField.Value = 1e-06;
            app.MaxIterEditFieldLabel = uilabel(app.SettingsPanel);
            app.MaxIterEditFieldLabel.Text = 'MaxIter';
            app.MaxIterEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.MaxIterEditField.RoundFractionalValues = 'on';
            app.MaxIterEditField.ValueDisplayFormat = '%d';
            app.MaxIterEditField.Value = 100;
            app.ShowGradientFieldCheckBox = uicheckbox(app.SettingsPanel);
            app.ShowGradientFieldCheckBox.Text = 'Show Gradient Field';
            app.AlgorithmDropDown = uidropdown(app.SettingsPanel);
            app.AlgorithmDropDown.Items = {'None', 'Gradient Descent', 'Newton Multidimensional', 'Nelder Mead', 'Simulated Annealing'};
            app.AlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDropDownValueChanged, true);
            app.AlgorithmDropDown.Value = 'None';
            app.AlphaEditFieldLabel = uilabel(app.SettingsPanel);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.AlphaEditField.Value = 0.2;
            app.PLOTButton = uibutton(app.SettingsPanel, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.amountpointsEditFieldLabel = uilabel(app.SettingsPanel);
            app.amountpointsEditFieldLabel.Text = 'amount points';
            app.amountpointsEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.amountpointsEditField.Limits = [2 Inf];
            app.amountpointsEditField.RoundFractionalValues = 'on';
            app.amountpointsEditField.ValueDisplayFormat = '%d';
            app.amountpointsEditField.Value = 50;
            app.RightPanel = uipanel(app.GridLayout);
            app.GraphicsPanel = uipanel(app.RightPanel);
            app.GraphicsPanel.Title = 'Graphics';
            app.UIAxes = uiaxes(app.GraphicsPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.GraphoptimizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GraphoptimizerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GraphoptimizerUIFigure)
        end
    end
end